classdef disc_cont_convolution < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ClearInputButton             matlab.ui.control.Button
        Signal2Panel                 matlab.ui.container.Panel
        EquationEditField_2          matlab.ui.control.EditField
        EquationEditField_2Label     matlab.ui.control.Label
        Signal2Panel_2               matlab.ui.container.Panel
        EquationEditField            matlab.ui.control.EditField
        EquationEditFieldLabel       matlab.ui.control.Label
        NCircularPanel               matlab.ui.container.Panel
        NumberofNEditField           matlab.ui.control.EditField
        NumberofNEditFieldLabel      matlab.ui.control.Label
        Label_15                     matlab.ui.control.Label
        SimulatorLabel               matlab.ui.control.Label
        ConvolutionLabel_2           matlab.ui.control.Label
        TabGroup                     matlab.ui.container.TabGroup
        MainMenuTab                  matlab.ui.container.Tab
        DiscreteConvolutionButton    matlab.ui.control.Button
        ContinuousConvolutionButton  matlab.ui.control.Button
        HelpButton                   matlab.ui.control.Button
        HelpTab                      matlab.ui.container.Tab
        BacktomenuButton             matlab.ui.control.Button
        Label_21                     matlab.ui.control.Label
        Label_10                     matlab.ui.control.Label
        Label_11                     matlab.ui.control.Label
        HelpLabel                    matlab.ui.control.Label
        Label_7                      matlab.ui.control.Label
        ContinuousConvolutionTab     matlab.ui.container.Tab
        BacktomenuButton_4           matlab.ui.control.Button
        AnswerLabel_2                matlab.ui.control.Label
        Label_9                      matlab.ui.control.Label
        UIAxes_7                     matlab.ui.control.UIAxes
        UIAxes_6                     matlab.ui.control.UIAxes
        UIAxes_5                     matlab.ui.control.UIAxes
        DiscreteConvolutionTab       matlab.ui.container.Tab
        BacktomenuButton_5           matlab.ui.control.Button
        AnswerLabel                  matlab.ui.control.Label
        Label_22                     matlab.ui.control.Label
        UIAxes_15                    matlab.ui.control.UIAxes
        UIAxes_14                    matlab.ui.control.UIAxes
        UIAxes_13                    matlab.ui.control.UIAxes
        UIAxes_12                    matlab.ui.control.UIAxes
        Label                        matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: EquationEditField
        function EquationEditFieldValueChanging(app, event)
        changingValue = event.Value;
        app.EquationEditField.Value = changingValue;
        end

        % Callback function
        function PlotButtonPushed(app, event)
            x = 0:1:10;
            y = 0:1:10;
            sinyal1 = app.EquationEditField.Value;
            stem(app.UIAxes_5, eval(sinyal1));   
        end

        % Value changing function: EquationEditField_2
        function EquationEditField_2ValueChanging(app, event)
        changingValue = event.Value;
        app.EquationEditField_2.Value = changingValue;
        end

        % Callback function
        function PlotButton_2Pushed(app, event)
            x = 0:1:10;
            y = 0:1:10;
            sinyal2 = app.EquationEditField_2.Value;
            stem(app.UIAxes_6, eval(sinyal2)); 
        end

        % Button pushed function: ContinuousConvolutionButton
        function ContinuousConvolutionButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.ContinuousConvolutionTab;
            
            x = -10:0.1:10;
            y = -10:0.1:10;
            sinyal1 = app.EquationEditField.Value;
            plot(app.UIAxes_5, eval(sinyal1)); 
            
            sinyal2 = app.EquationEditField_2.Value;
            plot(app.UIAxes_6, eval(sinyal2));
            
            sinyal3 = app.NumberofNEditField.Value;

            out = conv(eval(sinyal1), eval(sinyal2));
            plot(app.UIAxes_7,out);
        end

        % Callback function
        function ImageClicked(app, event)
            
        end

        % Callback function
        function InputButtonPushed(app, event)
            sinyal3 = app.NumberofNEditField.Value;
        end

        % Value changing function: NumberofNEditField
        function NumberofNEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Button pushed function: DiscreteConvolutionButton
        function DiscreteConvolutionButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.DiscreteConvolutionTab;
            
            x = -3:1:10;
            y = -3:1:10;
            sinyal1 = app.EquationEditField.Value;
            stem(app.UIAxes_12, eval(sinyal1)); 
            
            sinyal2 = app.EquationEditField_2.Value;
            stem(app.UIAxes_13, eval(sinyal2));
            
            sinyal3 = app.NumberofNEditField.Value;

            out = conv(eval(sinyal1), eval(sinyal2));
            out1 = cconv(eval(sinyal1), eval(sinyal2), eval(sinyal3));
            stem(app.UIAxes_14,out);
            stem(app.UIAxes_15,out1);
        end

        % Button down function: HelpTab
        function HelpTabButtonDown(app, event)
            
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.HelpTab;
        end

        % Callback function
        function TeoriButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.TeoriTab;
        end

        % Callback function
        function SoalButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.SoalTab;
        end

        % Button pushed function: BacktomenuButton
        function BacktomenuButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.MainMenuTab;
        end

        % Callback function
        function BacktomenuButton_2Pushed(app, event)
            app.TabGroup.SelectedTab = app.MainMenuTab;
        end

        % Callback function
        function BacktomenuButton_3Pushed(app, event)
            app.TabGroup.SelectedTab = app.MainMenuTab;
        end

        % Button pushed function: BacktomenuButton_4
        function BacktomenuButton_4Pushed(app, event)
            app.TabGroup.SelectedTab = app.MainMenuTab;
        end

        % Button pushed function: BacktomenuButton_5
        function BacktomenuButton_5Pushed(app, event)
            app.TabGroup.SelectedTab = app.MainMenuTab;
        end

        % Button pushed function: ClearInputButton
        function ClearInputButtonPushed(app, event)
            app.EquationEditField_2.Value = '';
            app.EquationEditField.Value = '';
            app.NumberofNEditField.Value = '';
            cla(app.UIAxes_5, 'reset');
            cla(app.UIAxes_6, 'reset');
            cla(app.UIAxes_7, 'reset');
            cla(app.UIAxes_12, 'reset');
            cla(app.UIAxes_13, 'reset');
            cla(app.UIAxes_14, 'reset');
            cla(app.UIAxes_15, 'reset');
        end

        % Value changed function: EquationEditField_2
        function EquationEditField_2ValueChanged(app, event)
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainMenuTab = uitab(app.TabGroup);
            app.MainMenuTab.Title = 'Main Menu';
            app.HelpButton = uibutton(app.MainMenuTab, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.ContinuousConvolutionButton = uibutton(app.MainMenuTab, 'push');
            app.ContinuousConvolutionButton.ButtonPushedFcn = createCallbackFcn(app, @ContinuousConvolutionButtonPushed, true);
            app.ContinuousConvolutionButton.Text = 'Continuous Convolution';
            app.DiscreteConvolutionButton = uibutton(app.MainMenuTab, 'push');
            app.DiscreteConvolutionButton.ButtonPushedFcn = createCallbackFcn(app, @DiscreteConvolutionButtonPushed, true);
            app.DiscreteConvolutionButton.Text = 'Discrete Convolution';
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.HelpTab.ButtonDownFcn = createCallbackFcn(app, @HelpTabButtonDown, true);
            app.Label_7 = uilabel(app.HelpTab);
            app.Label_7.Text = '';
            app.HelpLabel = uilabel(app.HelpTab);
            app.HelpLabel.Text = 'Help';
            app.Label_11 = uilabel(app.HelpTab);
            app.Label_11.Text = '';
            app.Label_10 = uilabel(app.HelpTab);
            app.Label_10.Text = {'Enter the 2 signal equations that you want to convolve.'; ''; 'If the equation is a continuous equation, signal 1 and signal 2 must use the '; 'variable x and/or variable y'; ''; 'If the equation is a discrete equation, signal 1 and signal 2 must be'; 'use "[" to start and "]" to close the discrete signal'; ''; 'Press the Plot button on each signal section to see a graph of the signal'; ''; 'Press the convolution button according to the desired convolution type to find out '; 'the signal convolution results'};
            app.Label_21 = uilabel(app.HelpTab);
            app.Label_21.Text = {'1.'; ''; '2.'; ''; ''; ''; ''; ''; '3.'; ''; ''; '4.'};
            app.BacktomenuButton = uibutton(app.HelpTab, 'push');
            app.BacktomenuButton.ButtonPushedFcn = createCallbackFcn(app, @BacktomenuButtonPushed, true);
            app.BacktomenuButton.Text = 'Back to menu';
            app.ContinuousConvolutionTab = uitab(app.TabGroup);
            app.ContinuousConvolutionTab.Title = 'Continuous Convolution';
            app.UIAxes_5 = uiaxes(app.ContinuousConvolutionTab);
            title(app.UIAxes_5, 'Sinyal 1')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_5.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_6 = uiaxes(app.ContinuousConvolutionTab);
            title(app.UIAxes_6, 'Sinyal 2')
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_7 = uiaxes(app.ContinuousConvolutionTab);
            title(app.UIAxes_7, 'Hasil Konvolusi Translasi')
            xlabel(app.UIAxes_7, 'X')
            ylabel(app.UIAxes_7, 'Y')
            zlabel(app.UIAxes_7, 'Z')
            app.Label_9 = uilabel(app.ContinuousConvolutionTab);
            app.Label_9.Text = '';
            app.AnswerLabel_2 = uilabel(app.ContinuousConvolutionTab);
            app.AnswerLabel_2.Text = 'Answer';
            app.BacktomenuButton_4 = uibutton(app.ContinuousConvolutionTab, 'push');
            app.BacktomenuButton_4.ButtonPushedFcn = createCallbackFcn(app, @BacktomenuButton_4Pushed, true);
            app.BacktomenuButton_4.Text = 'Back to menu';
            app.DiscreteConvolutionTab = uitab(app.TabGroup);
            app.DiscreteConvolutionTab.Title = 'Discrete Convolution';
            app.UIAxes_12 = uiaxes(app.DiscreteConvolutionTab);
            title(app.UIAxes_12, 'Sinyal 1')
            xlabel(app.UIAxes_12, 'X')
            ylabel(app.UIAxes_12, 'Y')
            zlabel(app.UIAxes_12, 'Z')
            app.UIAxes_13 = uiaxes(app.DiscreteConvolutionTab);
            title(app.UIAxes_13, 'Sinyal 2')
            xlabel(app.UIAxes_13, 'X')
            ylabel(app.UIAxes_13, 'Y')
            zlabel(app.UIAxes_13, 'Z')
            app.UIAxes_14 = uiaxes(app.DiscreteConvolutionTab);
            title(app.UIAxes_14, 'Hasil Konvolusi Translasi')
            xlabel(app.UIAxes_14, 'X')
            ylabel(app.UIAxes_14, 'Y')
            zlabel(app.UIAxes_14, 'Z')
            app.UIAxes_15 = uiaxes(app.DiscreteConvolutionTab);
            title(app.UIAxes_15, 'Hasil Konvolusi Circular')
            xlabel(app.UIAxes_15, 'X')
            ylabel(app.UIAxes_15, 'Y')
            zlabel(app.UIAxes_15, 'Z')
            app.Label_22 = uilabel(app.DiscreteConvolutionTab);
            app.Label_22.Text = '';
            app.AnswerLabel = uilabel(app.DiscreteConvolutionTab);
            app.AnswerLabel.Text = 'Answer';
            app.BacktomenuButton_5 = uibutton(app.DiscreteConvolutionTab, 'push');
            app.BacktomenuButton_5.ButtonPushedFcn = createCallbackFcn(app, @BacktomenuButton_5Pushed, true);
            app.BacktomenuButton_5.Text = 'Back to menu';
            app.ConvolutionLabel_2 = uilabel(app.UIFigure);
            app.ConvolutionLabel_2.Text = 'Convolution';
            app.SimulatorLabel = uilabel(app.UIFigure);
            app.SimulatorLabel.Text = 'Simulator';
            app.Label_15 = uilabel(app.UIFigure);
            app.NCircularPanel = uipanel(app.UIFigure);
            app.NCircularPanel.Title = 'N Circular';
            app.NumberofNEditFieldLabel = uilabel(app.NCircularPanel);
            app.NumberofNEditFieldLabel.Text = 'Number of N';
            app.NumberofNEditField = uieditfield(app.NCircularPanel, 'text');
            app.NumberofNEditField.ValueChangingFcn = createCallbackFcn(app, @NumberofNEditFieldValueChanging, true);
            app.Signal2Panel_2 = uipanel(app.UIFigure);
            app.Signal2Panel_2.Title = 'Signal 2';
            app.EquationEditFieldLabel = uilabel(app.Signal2Panel_2);
            app.EquationEditFieldLabel.Text = 'Equation';
            app.EquationEditField = uieditfield(app.Signal2Panel_2, 'text');
            app.EquationEditField.ValueChangingFcn = createCallbackFcn(app, @EquationEditFieldValueChanging, true);
            app.Signal2Panel = uipanel(app.UIFigure);
            app.Signal2Panel.Title = 'Signal 2';
            app.EquationEditField_2Label = uilabel(app.Signal2Panel);
            app.EquationEditField_2Label.Text = 'Equation';
            app.EquationEditField_2 = uieditfield(app.Signal2Panel, 'text');
            app.EquationEditField_2.ValueChangedFcn = createCallbackFcn(app, @EquationEditField_2ValueChanged, true);
            app.EquationEditField_2.ValueChangingFcn = createCallbackFcn(app, @EquationEditField_2ValueChanging, true);
            app.ClearInputButton = uibutton(app.UIFigure, 'push');
            app.ClearInputButton.ButtonPushedFcn = createCallbackFcn(app, @ClearInputButtonPushed, true);
            app.ClearInputButton.Text = 'Clear Input';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = disc_cont_convolution

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end